classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FindSpreadsTab                  matlab.ui.container.Tab
        FindSpreadsStatusLabel          matlab.ui.control.Label
        FindSpreadsNumOfLots            matlab.ui.control.NumericEditField
        NumberoflotsLabel               matlab.ui.control.Label
        FindSpreadsEntryCredit          matlab.ui.control.NumericEditField
        EntryCreditLabel                matlab.ui.control.Label
        FindSpreadsWidth                matlab.ui.control.NumericEditField
        WidthofSpreadLabel              matlab.ui.control.Label
        FindSpreadsButton               matlab.ui.control.Button
        FoundSpreadsPanel               matlab.ui.container.Panel
        FindSpreadsTable                matlab.ui.control.Table
        SubmitComboStopLimitTab         matlab.ui.container.Tab
        Submit1Status                   matlab.ui.control.Label
        Submit1AddLegsPanel             matlab.ui.container.Panel
        Submit1PutCall                  matlab.ui.control.DropDown
        SideLabel                       matlab.ui.control.Label
        Submit1Strike                   matlab.ui.control.NumericEditField
        StrikePriceLabel                matlab.ui.control.Label
        Submit1AddLeg                   matlab.ui.control.Button
        Submit1EditParentOrderPanel     matlab.ui.container.Panel
        NumberOfLotsEditField_4Label    matlab.ui.control.Label
        Submit1ParentLimit              matlab.ui.control.EditField
        Submit1ParentStop               matlab.ui.control.EditField
        Submit1NumOfLots                matlab.ui.control.NumericEditField
        Submit1AddEditsParent           matlab.ui.control.Button
        ParentOrderStopPriceEditField_2Label  matlab.ui.control.Label
        ParentOrderLimitPriceEditField_2Label  matlab.ui.control.Label
        Submit1AddStopLossPanel         matlab.ui.container.Panel
        StopLossStopLimitLabel          matlab.ui.control.Label
        Submit1STPLOSOrderType          matlab.ui.control.DropDown
        Submit1STPLOSStop               matlab.ui.control.EditField
        StopLossStopTriggerEditField_2Label  matlab.ui.control.Label
        Submit1STPLOSLimit              matlab.ui.control.EditField
        Submit1AddEditsSTPLOS           matlab.ui.control.Button
        StopLossOrderTypeDropDown_2Label  matlab.ui.control.Label
        Submit1OrderSummaryPanel        matlab.ui.container.Panel
        ClearTableButton                matlab.ui.control.Button
        StopLossStopLimitLabel_2        matlab.ui.control.Label
        Submit1ReciptSTPLOSLimit        matlab.ui.control.EditField
        Submit1ReciptSTPLOSStop         matlab.ui.control.EditField
        StopLossStopTriggerLabel        matlab.ui.control.Label
        Submit1ReciptSTPLOSType         matlab.ui.control.EditField
        StopLossTypeEditFieldLabel      matlab.ui.control.Label
        Submit1ReciptStop               matlab.ui.control.EditField
        ParentOrderStopPriceEditFieldLabel  matlab.ui.control.Label
        Submit1ReciptLimit              matlab.ui.control.EditField
        ParentOrderLimitPriceEditFieldLabel  matlab.ui.control.Label
        Submit1ReciptNumOfLot           matlab.ui.control.NumericEditField
        NumberOfLotsEditField_2Label    matlab.ui.control.Label
        Submit1                         matlab.ui.control.Button
        Submit1Table                    matlab.ui.control.Table
        OrderReportTab                  matlab.ui.container.Tab
        LastUpdate                      matlab.ui.control.Label
        UpdateTable                     matlab.ui.control.Button
        ReportTable                     matlab.ui.control.Table
        CancelOrderTab                  matlab.ui.container.Tab
        CancelStatus                    matlab.ui.control.Label
        OrderIdEditField                matlab.ui.control.NumericEditField
        OrderIdEditFieldLabel           matlab.ui.control.Label
        CancelOrderButton               matlab.ui.control.Button
        SubmitSingleTab                 matlab.ui.container.Tab
        SingleStatusLabel               matlab.ui.control.Label
        Submit1AddLegsPanel_3           matlab.ui.container.Panel
        SingleStrike                    matlab.ui.control.NumericEditField
        SingleAction                    matlab.ui.control.DropDown
        SingleRight                     matlab.ui.control.DropDown
        SingleNumOfLots                 matlab.ui.control.NumericEditField
        SingleOrderType                 matlab.ui.control.DropDown
        SingleLimitPriceLabel           matlab.ui.control.Label
        SingleLimitPriceField           matlab.ui.control.EditField
        SingleStopPriceLabel            matlab.ui.control.Label
        SingleStopPriceField            matlab.ui.control.EditField
        OrderTypeLabel                  matlab.ui.control.Label
        NumberOfLotsEditField_4Label_3  matlab.ui.control.Label
        SideLabel_4                     matlab.ui.control.Label
        SideLabel_3                     matlab.ui.control.Label
        StrikePriceLabel_3              matlab.ui.control.Label
        SubmitSingle                    matlab.ui.control.Button
        SubmitComboTab                  matlab.ui.container.Tab
        Submit2Status                   matlab.ui.control.Label
        Submit2AddLegsPanel             matlab.ui.container.Panel
        Submit2AddLegActionDd           matlab.ui.control.DropDown
        SideLabel_5                     matlab.ui.control.Label
        Submit2AddLegRightDd            matlab.ui.control.DropDown
        SideLabel_2                     matlab.ui.control.Label
        Submit2AddLegStrikeField        matlab.ui.control.NumericEditField
        StrikePriceLabel_2              matlab.ui.control.Label
        Submit2AddLegBtn                matlab.ui.control.Button
        Submit2EditParentOrderPanel     matlab.ui.container.Panel
        Submit2ParentOrderStopPriceLabel  matlab.ui.control.Label
        Submit2ParentOrderLimitPriceLabel  matlab.ui.control.Label
        Submit2ParentOrderType          matlab.ui.control.DropDown
        ParentOrderTypeDropDownLabel    matlab.ui.control.Label
        NumberOfLotsEditField_4Label_2  matlab.ui.control.Label
        ActionDropDown                  matlab.ui.control.DropDown
        ActionDropDownLabel             matlab.ui.control.Label
        Submit2NumOfLots                matlab.ui.control.NumericEditField
        Submit2AddEditsParent           matlab.ui.control.Button
        Submit2ParentStop               matlab.ui.control.EditField
        Submit2ParentLimit              matlab.ui.control.EditField
        Submit2AddStopLossPanel         matlab.ui.container.Panel
        Submit2StopLossStopLimitLabel   matlab.ui.control.Label
        Submit2STPLOSStop               matlab.ui.control.EditField
        StopLossStopTriggerEditField_2Label_2  matlab.ui.control.Label
        Submit2STPLOSLimit              matlab.ui.control.EditField
        Submit2AddEditsSTPLOS           matlab.ui.control.Button
        Submit2STPLOSOrderType          matlab.ui.control.DropDown
        StopLossOrderTypeDropDown_2Label_2  matlab.ui.control.Label
        Submit2ProfitTakerPanel         matlab.ui.container.Panel
        Submit2ProfitTakerCb            matlab.ui.control.CheckBox
        ProfitTakerLimitPriceLabel      matlab.ui.control.Label
        Submit2ProfitTakerField         matlab.ui.control.EditField
        Submit2ProfitTakerBtn           matlab.ui.control.Button
        Submit2OrderSummaryPanel        matlab.ui.container.Panel
        Submit2ReciptProfitTaker        matlab.ui.control.EditField
        ParentOrderActionEditField      matlab.ui.control.EditField
        ParentOrderActionEditFieldLabel  matlab.ui.control.Label
        Submit2Table                    matlab.ui.control.Table
        Submit2ReciptClearTableBtn      matlab.ui.control.Button
        ProfitTakerEditFieldLabel       matlab.ui.control.Label
        ParentOrderTypeEditFieldLabel   matlab.ui.control.Label
        Submit2ReciptParentOrderType    matlab.ui.control.EditField
        Submit2ReciptLimitPriceLabel    matlab.ui.control.Label
        Submit2ReciptLimit              matlab.ui.control.EditField
        Submit2ReciptStopPriceLabel     matlab.ui.control.Label
        Submit2ReciptStop               matlab.ui.control.EditField
        Submit2ReciptNumOfLot           matlab.ui.control.NumericEditField
        Submit2ReciptSTPLOSType         matlab.ui.control.EditField
        Submit2ReciptSTPLOSStop         matlab.ui.control.EditField
        Submit2ReciptStopLossStopLimitLabel  matlab.ui.control.Label
        Submit2ReciptSTPLOSLimit        matlab.ui.control.EditField
        StopLossStopTriggerLabel_2      matlab.ui.control.Label
        StopLossTypeEditFieldLabel_2    matlab.ui.control.Label
        NumberOfLotsEditField_2Label_2  matlab.ui.control.Label
        Submit2ReciptProfitTakerEnable  matlab.ui.control.EditField
        Submit2ProfitTakerLabel         matlab.ui.control.Label
        Submit2ReciptSubmit             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindSpreadsButton
        function FindSpreadsButtonPushed(app, event)
            app.FindSpreadsStatusLabel.Text = 'Working...';
            drawnow
            
            try
                spreads = cell(py.find_spreads.get_spreads(app.FindSpreadsWidth.Value, "2:00", app.FindSpreadsEntryCredit.Value, app.FindSpreadsNumOfLots.Value));
            
                callSpreads = spreads{1};
                putSpreads = spreads{2};
                
                call_strikes = cell(callSpreads);
                put_strikes = cell(putSpreads);
                
                % Sort and convert to strings
                sortedStrikes = sort([call_strikes{1:2},put_strikes{1:2}]);
                sortedStrikes = arrayfun(@(x) string(x), sortedStrikes);
                
                longPutStrike = double(sortedStrikes(1));
                shortPutStrike = double(sortedStrikes(2));
                shortCallStrike = double(sortedStrikes(3));
                longCallStrike = double(sortedStrikes(4));
    
                app.FindSpreadsTable.Data={''};
                app.FindSpreadsTable.Data(1, 1:3) = {longCallStrike 'Call' 'Buy'};
                app.FindSpreadsTable.Data(2, 1:3) = {shortCallStrike 'Call' 'Sell'};
                app.FindSpreadsTable.Data(3, 1:3) = {shortPutStrike 'Put' 'Sell'};
                app.FindSpreadsTable.Data(4, 1:3) = {longPutStrike 'Put' 'Buy'};

                app.FindSpreadsStatusLabel.Text = 'Done';

            catch e
                if (e.message == "Python Error: ConnectionRefusedError: [WinError 1225] The remote computer refused the network connection")
                    app.FindSpreadsStatusLabel.Text = "TWS API must be running.";
                else
                    app.FindSpreadsStatusLabel.Text = e.message;
                end
            end
        end

        % Button pushed function: Submit1AddLeg
        function Submit1AddLegPushed(app, event)
            % Retrieve strike price and option type from the UI
            strikePrice = app.Submit1Strike.Value;
            optionType = app.Submit1PutCall.Value;
            
            % Get current table data
            data = app.Submit1Table.Data;
            
            % Add new row to the table
            newRow = {strikePrice, optionType};
            data = [data; newRow];
            
            % Update the table with the new data
            app.Submit1Table.Data = data;
        end

        % Button pushed function: Submit1AddEditsSTPLOS
        function Submit1AddEditsSTPLOSPushed(app, event)
            % Update stop loss information
            if app.Submit1STPLOSOrderType.Value == "Stop Market"
                app.StopLossStopLimitLabel_2.Visible = "off";
                app.Submit1ReciptSTPLOSLimit.Visible = "off";
            else
                app.StopLossStopLimitLabel_2.Visible = "on";
                app.Submit1ReciptSTPLOSLimit.Visible = "on";
            end

            app.Submit1ReciptSTPLOSType.Value = app.Submit1STPLOSOrderType.Value;
            app.Submit1ReciptSTPLOSStop.Value = app.Submit1STPLOSStop.Value;
            app.Submit1ReciptSTPLOSLimit.Value = app.Submit1STPLOSLimit.Value;
        end

        % Button pushed function: Submit1AddEditsParent
        function Submit1AddEditsParentButtonPushed(app, event)
            % Update parent order information
            app.Submit1ReciptLimit.Value = app.Submit1ParentLimit.Value;
            app.Submit1ReciptStop.Value = app.Submit1ParentStop.Value;
            app.Submit1ReciptNumOfLot.Value = app.Submit1NumOfLots.Value;
        end

        % Button pushed function: Submit1
        function Submit1ButtonPushed(app, event)
            % Retrieve data from the table
            data = app.Submit1Table.Data;
            
            % Initialize spreads
            callSpread = [];
            putSpread = [];
            
            if size(data, 1) > 4
                app.Submit1Status.Text = "Too many options.";
                return
            end

            % Loop through the table data to populate spreads
            for i = 1:size(data, 1)
                strikePrice = data{i, 1};
                optionType = data{i, 2};
                
                % Append to the appropriate spread
                if strcmp(optionType, 'Call')
                    callSpread = [callSpread, strikePrice];
                elseif strcmp(optionType, 'Put')
                    putSpread = [putSpread, strikePrice];
                end
            end
            
            % Ensure we have at least two elements for callSpread and putSpread
            if length(callSpread) < 2
                app.Submit1Status.Text = "Not enough options for call spread.";
                return
            end
            if length(putSpread) < 2
                app.Submit1Status.Text = "Not enough options for put spread.";
                return
            end
            
            % Extract other parameters from the UI
            nofLots = double(app.Submit1ReciptNumOfLot.Value);
            stopTriggerPrice = str2double(app.Submit1ReciptStop.Value);
            stopLimitPrice = str2double(app.Submit1ReciptLimit.Value);
            stopLossTriggerPrice = str2double(app.Submit1ReciptSTPLOSStop.Value);
            stopLossLimitPrice = str2double(app.Submit1ReciptSTPLOSLimit.Value);
            
            if strcmp(app.Submit1ReciptSTPLOSType.Value, "Stop Limit")
                stopLossType = "STP LMT";
            elseif strcmp(app.Submit1ReciptSTPLOSType.Value, "Stop Market")
                stopLossType = "STP";
            else
                stopLossType = "foo";
            end

            % Call the order_condor function
            try
                orderId = py.order_condor.order_condor(callSpread, putSpread, nofLots, ...
                                                       stopTriggerPrice, stopLimitPrice, ...
                                                       stopLossType, stopLossTriggerPrice, stopLossLimitPrice);
                
                % Display the order ID
                app.Submit1Status.Text = "Order Submitted with ID: " + string(double(orderId));
            catch ME
                % Handle errors
                app.Submit1Status.Text = "Error submitting order: " + string(ME.message);
            end
        end

        % Button pushed function: UpdateTable
        function UpdateTableButtonPushed(app, event)
            % Fetch the execution data
            data = py.executions.get_executions();

            % Convert Python list of dicts to MATLAB cell array
            data = cell(data);

            % Determine the number of rows
            numRows = numel(data);
            formattedData = cell(numRows, 6);

            for i = 1:numRows
                dict = data{i};
                formattedData{i, 1} = char(dict{'Action'}); % action (string)
                formattedData{i, 2} = char(dict{'Fin Instrument'}); % fin instrument (string)
                formattedData{i, 3} = double(dict{'Price'}); % price (float)
                formattedData{i, 4} = char(dict{'Time'}); % time (string)
                formattedData{i, 5} = double(dict{'Commission'}); % commission (float)
                formattedData{i, 6} = double(dict{'Quantity'}); % quantity (integer)
            end

            % Update the table
            app.ReportTable.Data = formattedData;

            % Update the last update label
            currentTime = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            app.LastUpdate.Text = ['Last Update: ' char(currentTime)];
        end

        % Button pushed function: CancelOrderButton
        function CancelOrderButtonPushed(app, event)
            if ~py.cancel_order.cancel_order(app.OrderIdEditField.Value)
                app.CancelStatus.Text = "Order not found.";
            else
                app.CancelStatus.Text = "Order Cancelled.";
            end

        end

        % Value changed function: Submit1STPLOSOrderType
        function Submit1STPLOSOrderTypeValueChanged(app, event)
            selectedType = app.Submit1STPLOSOrderType.Value;
    
            if strcmp(selectedType, 'Stop Market')
                % Hide the Stop Loss Stop Limit edit field
                app.Submit1STPLOSLimit.Value = '';
                app.Submit1STPLOSLimit.Visible = 'off';
                app.StopLossStopLimitLabel.Visible = 'off';
            else
                % Show the Stop Loss Stop Limit edit field
                app.Submit1STPLOSLimit.Visible = 'on';
                app.StopLossStopLimitLabel.Visible = 'on';
            end
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            app.Submit1Table.Data = {};
        end

        % Button pushed function: SubmitSingle
        function SubmitSingleButtonPushed(app, event)
            % Extract values from the app fields
            strike = app.SingleStrike.Value;
            action = app.SingleAction.Value;
            right = app.SingleRight.Value;
            nof_lot = app.SingleNumOfLots.Value;
            limit_price = app.SingleLimitPriceField.Value;
            stop_price = app.SingleStopPriceField.Value;

            if app.SingleOrderType.Value == "Market"
               order_type = "MKT";
            elseif app.SingleOrderType.Value == "Limit"
                order_type = "LMT"
            elseif app.SingleOrderType.Value == "Stop"
                order_type = "STP"
            else
                order_type = "STP LMT"
            end
            
            % Call the Python function
            orderId = py.order_single.order_single(strike, action, right, nof_lot, order_type, limit_price, stop_price);
            app.SingleStatusLabel.Text = "Order Submitted with ID: " + string(double(orderId));
        end

        % Value changed function: SingleOrderType
        function SingleOrderTypeValueChanged(app, event)
            value = app.SingleOrderType.Value;
            
            if value == "Market"
                app.SingleLimitPriceLabel.Visible = 'off';
                app.SingleLimitPriceField.Visible = 'off';
                app.SingleStopPriceLabel.Visible = 'off';
                app.SingleStopPriceField.Visible = 'off';
            elseif value == "Limit"
                app.SingleLimitPriceLabel.Visible = 'on';
                app.SingleLimitPriceField.Visible = 'on';
                app.SingleStopPriceLabel.Visible = 'off';
                app.SingleStopPriceField.Visible = 'off';
            elseif value == "Stop"
                app.SingleLimitPriceLabel.Visible = 'off';
                app.SingleLimitPriceField.Visible = 'off';
                app.SingleStopPriceLabel.Visible = 'on';
                app.SingleStopPriceField.Visible = 'on';
            else
                app.SingleLimitPriceLabel.Visible = 'on';
                app.SingleLimitPriceField.Visible = 'on';
                app.SingleStopPriceLabel.Visible = 'on';
                app.SingleStopPriceField.Visible = 'on';
            end
        end

        % Value changed function: Submit2ParentOrderType
        function Submit2ParentOrderTypeValueChanged(app, event)
            value = app.Submit2ParentOrderType.Value;
            
            if value == "Market"
                app.Submit2ParentOrderLimitPriceLabel.Visible = 'off';
                app.Submit2ParentLimit.Visible = 'off';
                app.Submit2ParentOrderStopPriceLabel.Visible = 'off';
                app.Submit2ParentStop.Visible = 'off';
            elseif value == "Limit"
                app.Submit2ParentOrderLimitPriceLabel.Visible = 'on';
                app.Submit2ParentLimit.Visible = 'on';
                app.Submit2ParentOrderStopPriceLabel.Visible = 'off';
                app.Submit2ParentStop.Visible = 'off';
            elseif value == "Stop"
                app.Submit2ParentOrderLimitPriceLabel.Visible = 'off';
                app.Submit2ParentLimit.Visible = 'off';
                app.Submit2ParentOrderStopPriceLabel.Visible = 'on';
                app.Submit2ParentStop.Visible = 'on';
            else
                app.Submit2ParentOrderLimitPriceLabel.Visible = 'on';
                app.Submit2ParentLimit.Visible = 'on';
                app.Submit2ParentOrderStopPriceLabel.Visible = 'on';
                app.Submit2ParentStop.Visible = 'on';
            end
        end

        % Button pushed function: Submit2AddLegBtn
        function Submit2AddLegBtnButtonPushed(app, event)
            % Retrieve strike price and option type from the UI
            strikePrice = app.Submit2AddLegStrikeField.Value;
            actionType = app.Submit2AddLegActionDd.Value;
            optionType = app.Submit2AddLegRightDd.Value;
            
            % Get current table data
            data = app.Submit2Table.Data;
            
            % Add new row to the table
            newRow = {strikePrice, actionType, optionType};
            data = [data; newRow];
            
            % Update the table with the new data
            app.Submit2Table.Data = data;
        end

        % Button pushed function: Submit2AddEditsParent
        function Submit2AddEditsParentButtonPushed(app, event)
            orderType = app.Submit2ParentOrderType.Value;

           % Hide order summary fields depending on type
            if orderType == "Market"
                app.Submit2ReciptLimitPriceLabel.Visible = 'off';
                app.Submit2ReciptLimit.Visible = 'off';
                app.Submit2ReciptStopPriceLabel.Visible = 'off';
                app.Submit2ReciptStop.Visible = 'off';
            elseif orderType == "Limit"
                app.Submit2ReciptLimitPriceLabel.Visible = 'on';
                app.Submit2ReciptLimit.Visible = 'on';
                app.Submit2ReciptStopPriceLabel.Visible = 'off';
                app.Submit2ReciptStop.Visible = 'off';
            elseif orderType == "Stop"
                app.Submit2ReciptLimitPriceLabel.Visible = 'off';
                app.Submit2ReciptLimit.Visible = 'off';
                app.Submit2ReciptStopPriceLabel.Visible = 'on';
                app.Submit2ReciptStop.Visible = 'on';
            else
                app.Submit2ReciptLimitPriceLabel.Visible = 'on';
                app.Submit2ReciptLimit.Visible = 'on';
                app.Submit2ReciptStopPriceLabel.Visible = 'on';
                app.Submit2ReciptStop.Visible = 'on';
            end

            % Update parent order information
            app.Submit2ReciptParentOrderType.Value = orderType;
            app.ParentOrderActionEditField.Value = app.ActionDropDown.Value;
            app.Submit2ReciptLimit.Value = app.Submit2ParentLimit.Value;
            app.Submit2ReciptStop.Value = app.Submit2ParentStop.Value;
            app.Submit2ReciptNumOfLot.Value = app.Submit2NumOfLots.Value;
        end

        % Value changed function: Submit2STPLOSOrderType
        function Submit2STPLOSOrderTypeValueChanged(app, event)
            selectedType = app.Submit2STPLOSOrderType.Value;
    
            if selectedType == "Stop Market"
                % Hide the Stop Loss Stop Limit edit field
                app.Submit2StopLossStopLimitLabel.Visible = "off";
                app.Submit2STPLOSLimit.Visible = "off";
            else
                % Show the Stop Loss Stop Limit edit field
                app.Submit2StopLossStopLimitLabel.Visible = "on";
                app.Submit2STPLOSLimit.Visible = "on";
            end
        end

        % Button pushed function: Submit2AddEditsSTPLOS
        function Submit2AddEditsSTPLOSButtonPushed(app, event)
            % Update stop loss information
            orderType = app.Submit2STPLOSOrderType.Value;

            if orderType == "Stop Market"
                app.Submit2ReciptStopLossStopLimitLabel.Visible = "off";
                app.Submit2ReciptSTPLOSLimit.Visible = "off";
            else
                app.Submit2ReciptStopLossStopLimitLabel.Visible = "on";
                app.Submit2ReciptSTPLOSLimit.Visible = "on";
            end

            app.Submit2ReciptSTPLOSType.Value = orderType;
            app.Submit2ReciptSTPLOSStop.Value = app.Submit2STPLOSStop.Value;
            app.Submit2ReciptSTPLOSLimit.Value = app.Submit2STPLOSLimit.Value;
        end

        % Value changed function: Submit2ProfitTakerCb
        function Submit2ProfitTakerCbValueChanged(app, event)
            value = app.Submit2ProfitTakerCb.Value;

            if value == 1
                app.ProfitTakerLimitPriceLabel.Visible = 'on';
                app.Submit2ProfitTakerField.Visible = "on";
            else
                app.ProfitTakerLimitPriceLabel.Visible = 'off';
                app.Submit2ProfitTakerField.Visible = "off";
            end
        end

        % Button pushed function: Submit2ProfitTakerBtn
        function Submit2ProfitTakerBtnButtonPushed(app, event)
            value = app.Submit2ProfitTakerCb.Value;
            
            if value == 1
                app.Submit2ReciptProfitTakerEnable.Value = "Enabled";
                app.Submit2ProfitTakerLabel.Visible = 'on';
                app.Submit2ReciptProfitTaker.Visible = 'on';
            else
                app.Submit2ReciptProfitTakerEnable.Value = "Disabled";
                app.Submit2ProfitTakerLabel.Visible = 'off';
                app.Submit2ReciptProfitTaker.Visible = 'off';
            end

            app.Submit2ReciptProfitTaker.Value = app.Submit2ProfitTakerField.Value;
        end

        % Button pushed function: Submit2ReciptSubmit
        function Submit2ReciptSubmitButtonPushed(app, event)
            % Retrieve data from the table
            data = app.Submit2Table.Data;
            
            % Initialize spreads
            legs = [];

            % Loop through the table data to populate spreads
            for i = 1:size(data, 1)
                strikePrice = data{i, 1};
                action = data{i, 2};
                right = data{i, 3};
                
                % Append to the legs list
                legs{end+1} = {strikePrice, action, right};
            end
            
            % Extract other parameters from the UI
            if app.Submit2ReciptParentOrderType.Value == "Market"
                orderType = "MKT";
            elseif app.Submit2ReciptParentOrderType.Value == "Stop"
                orderType = "STP";
            elseif app.Submit2ReciptParentOrderType.Value == "Limit"
                orderType = "LMT";
            else
                orderType = "STP LMT";
            end
            parentAction = app.ParentOrderActionEditField.Value;
            stopLimitPrice = str2double(app.Submit2ReciptLimit.Value);
            stopTriggerPrice = str2double(app.Submit2ReciptStop.Value);
            nOfLots = double(app.Submit2ReciptNumOfLot.Value);

            if app.Submit2ReciptSTPLOSType.Value == "Stop Limit"
                stopLossType = "STP LMT";
            else
                stopLossType = "STP";

            end
            stopLossTriggerPrice = str2double(app.Submit2ReciptSTPLOSStop.Value);
            stopLossLimitPrice = str2double(app.Submit2ReciptSTPLOSLimit.Value);
            
            if app.Submit2ReciptProfitTakerEnable.Value == "Enabled"
                profitTaker = true;
                profitTakerLimit = str2double(app.Submit2ReciptProfitTaker.Value);
            else
                profitTaker = false;
                profitTakerLimit = 0;
            end

            % Call the order_condor function
            try
                orderId = py.order_combo.order_combo_profit_taker(legs, parentAction, nOfLots, ...
                                                                  orderType, stopLimitPrice, stopTriggerPrice, ...
                                                                  stopLossType, stopLossLimitPrice, stopLossTriggerPrice, ...
                                                                  profitTaker, profitTakerLimit);
                
                % Display the order ID
                app.Submit2Status.Text = "Order Submitted with ID: " + string(double(orderId));
            catch ME
                % Handle errors
                app.Submit2Status.Text = "Error submitting order: " + string(ME.message);
            end
        end

        % Button pushed function: Submit2ReciptClearTableBtn
        function Submit2ReciptClearTableBtnButtonPushed(app, event)
            app.Submit2Table.Data = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FindSpreadsTab = uitab(app.TabGroup);
            app.FindSpreadsTab.Title = 'Find Spreads';
            app.FoundSpreadsPanel = uipanel(app.FindSpreadsTab);
            app.FoundSpreadsPanel.Title = 'Found Spreads';
            app.FindSpreadsTable = uitable(app.FoundSpreadsPanel);
            app.FindSpreadsTable.ColumnName = {'Strike Price'; 'Side'; 'Action'};
            app.FindSpreadsTable.RowName = {};
            app.FindSpreadsButton = uibutton(app.FindSpreadsTab, 'push');
            app.FindSpreadsButton.ButtonPushedFcn = createCallbackFcn(app, @FindSpreadsButtonPushed, true);
            app.FindSpreadsButton.Text = 'Find Spreads';
            app.WidthofSpreadLabel = uilabel(app.FindSpreadsTab);
            app.WidthofSpreadLabel.Text = 'Width of Spreads:';
            app.FindSpreadsWidth = uieditfield(app.FindSpreadsTab, 'numeric');
            app.FindSpreadsWidth.Limits = [0 Inf];
            app.FindSpreadsWidth.RoundFractionalValues = 'on';
            app.FindSpreadsWidth.Value = 10;
            app.EntryCreditLabel = uilabel(app.FindSpreadsTab);
            app.EntryCreditLabel.Text = 'Entry Credit:';
            app.FindSpreadsEntryCredit = uieditfield(app.FindSpreadsTab, 'numeric');
            app.FindSpreadsEntryCredit.Value = 1;
            app.NumberoflotsLabel = uilabel(app.FindSpreadsTab);
            app.NumberoflotsLabel.Text = 'Number of lots:';
            app.FindSpreadsNumOfLots = uieditfield(app.FindSpreadsTab, 'numeric');
            app.FindSpreadsNumOfLots.Limits = [1 Inf];
            app.FindSpreadsNumOfLots.Value = 1;
            app.FindSpreadsStatusLabel = uilabel(app.FindSpreadsTab);
            app.FindSpreadsStatusLabel.Text = 'Status: Ready';
            app.SubmitComboStopLimitTab = uitab(app.TabGroup);
            app.SubmitComboStopLimitTab.Title = 'Submit Combo Stop Limit';
            app.Submit1OrderSummaryPanel = uipanel(app.SubmitComboStopLimitTab);
            app.Submit1OrderSummaryPanel.Title = 'Order Summary';
            app.Submit1Table = uitable(app.Submit1OrderSummaryPanel);
            app.Submit1Table.ColumnName = {'Strike Price'; 'Right'};
            app.Submit1Table.RowName = {};
            app.Submit1 = uibutton(app.Submit1OrderSummaryPanel, 'push');
            app.Submit1.ButtonPushedFcn = createCallbackFcn(app, @Submit1ButtonPushed, true);
            app.Submit1.Text = 'Submit Order';
            app.NumberOfLotsEditField_2Label = uilabel(app.Submit1OrderSummaryPanel);
            app.NumberOfLotsEditField_2Label.Text = 'Number Of Lots:';
            app.Submit1ReciptNumOfLot = uieditfield(app.Submit1OrderSummaryPanel, 'numeric');
            app.Submit1ReciptNumOfLot.Limits = [1 Inf];
            app.Submit1ReciptNumOfLot.Editable = 'off';
            app.Submit1ReciptNumOfLot.Value = 1;
            app.ParentOrderLimitPriceEditFieldLabel = uilabel(app.Submit1OrderSummaryPanel);
            app.ParentOrderLimitPriceEditFieldLabel.Text = 'Parent Order Limit Price:';
            app.Submit1ReciptLimit = uieditfield(app.Submit1OrderSummaryPanel, 'text');
            app.Submit1ReciptLimit.Editable = 'off';
            app.ParentOrderStopPriceEditFieldLabel = uilabel(app.Submit1OrderSummaryPanel);
            app.ParentOrderStopPriceEditFieldLabel.Text = 'Parent Order Stop Price:';
            app.Submit1ReciptStop = uieditfield(app.Submit1OrderSummaryPanel, 'text');
            app.Submit1ReciptStop.Editable = 'off';
            app.StopLossTypeEditFieldLabel = uilabel(app.Submit1OrderSummaryPanel);
            app.StopLossTypeEditFieldLabel.Text = 'Stop Loss Type:';
            app.Submit1ReciptSTPLOSType = uieditfield(app.Submit1OrderSummaryPanel, 'text');
            app.Submit1ReciptSTPLOSType.Editable = 'off';
            app.StopLossStopTriggerLabel = uilabel(app.Submit1OrderSummaryPanel);
            app.StopLossStopTriggerLabel.Text = 'Stop Loss Stop Trigger:';
            app.Submit1ReciptSTPLOSStop = uieditfield(app.Submit1OrderSummaryPanel, 'text');
            app.Submit1ReciptSTPLOSLimit = uieditfield(app.Submit1OrderSummaryPanel, 'text');
            app.StopLossStopLimitLabel_2 = uilabel(app.Submit1OrderSummaryPanel);
            app.StopLossStopLimitLabel_2.Text = 'Stop Loss Stop Limit:';
            app.ClearTableButton = uibutton(app.Submit1OrderSummaryPanel, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.Submit1AddStopLossPanel = uipanel(app.SubmitComboStopLimitTab);
            app.Submit1AddStopLossPanel.Title = 'Add Stop Loss';
            app.StopLossOrderTypeDropDown_2Label = uilabel(app.Submit1AddStopLossPanel);
            app.StopLossOrderTypeDropDown_2Label.Text = 'Stop Loss Order Type:';
            app.Submit1AddEditsSTPLOS = uibutton(app.Submit1AddStopLossPanel, 'push');
            app.Submit1AddEditsSTPLOS.ButtonPushedFcn = createCallbackFcn(app, @Submit1AddEditsSTPLOSPushed, true);
            app.Submit1AddEditsSTPLOS.Text = 'Add Edits';
            app.Submit1STPLOSLimit = uieditfield(app.Submit1AddStopLossPanel, 'text');
            app.Submit1STPLOSLimit.Visible = 'off';
            app.StopLossStopTriggerEditField_2Label = uilabel(app.Submit1AddStopLossPanel);
            app.StopLossStopTriggerEditField_2Label.Text = 'Stop Loss Stop Trigger:';
            app.Submit1STPLOSStop = uieditfield(app.Submit1AddStopLossPanel, 'text');
            app.Submit1STPLOSOrderType = uidropdown(app.Submit1AddStopLossPanel);
            app.Submit1STPLOSOrderType.Items = {'Stop Market', 'Stop Limit'};
            app.Submit1STPLOSOrderType.ValueChangedFcn = createCallbackFcn(app, @Submit1STPLOSOrderTypeValueChanged, true);
            app.Submit1STPLOSOrderType.Value = 'Stop Market';
            app.StopLossStopLimitLabel = uilabel(app.Submit1AddStopLossPanel);
            app.StopLossStopLimitLabel.Visible = 'off';
            app.StopLossStopLimitLabel.Text = 'Stop Loss Stop Limit:';
            app.Submit1EditParentOrderPanel = uipanel(app.SubmitComboStopLimitTab);
            app.Submit1EditParentOrderPanel.Title = 'Edit Parent Order';
            app.ParentOrderLimitPriceEditField_2Label = uilabel(app.Submit1EditParentOrderPanel);
            app.ParentOrderLimitPriceEditField_2Label.Text = 'Parent Order Limit Price:';
            app.ParentOrderStopPriceEditField_2Label = uilabel(app.Submit1EditParentOrderPanel);
            app.ParentOrderStopPriceEditField_2Label.Text = 'Parent Order Stop Price:';
            app.Submit1AddEditsParent = uibutton(app.Submit1EditParentOrderPanel, 'push');
            app.Submit1AddEditsParent.ButtonPushedFcn = createCallbackFcn(app, @Submit1AddEditsParentButtonPushed, true);
            app.Submit1AddEditsParent.Text = 'Add Edits';
            app.Submit1NumOfLots = uieditfield(app.Submit1EditParentOrderPanel, 'numeric');
            app.Submit1NumOfLots.Limits = [1 Inf];
            app.Submit1NumOfLots.Value = 1;
            app.Submit1ParentStop = uieditfield(app.Submit1EditParentOrderPanel, 'text');
            app.Submit1ParentLimit = uieditfield(app.Submit1EditParentOrderPanel, 'text');
            app.NumberOfLotsEditField_4Label = uilabel(app.Submit1EditParentOrderPanel);
            app.NumberOfLotsEditField_4Label.Text = 'Number Of Lots:';
            app.Submit1AddLegsPanel = uipanel(app.SubmitComboStopLimitTab);
            app.Submit1AddLegsPanel.Title = 'Add Legs';
            app.Submit1AddLeg = uibutton(app.Submit1AddLegsPanel, 'push');
            app.Submit1AddLeg.ButtonPushedFcn = createCallbackFcn(app, @Submit1AddLegPushed, true);
            app.Submit1AddLeg.Text = 'Add Leg';
            app.StrikePriceLabel = uilabel(app.Submit1AddLegsPanel);
            app.StrikePriceLabel.Text = 'Strike Price:';
            app.Submit1Strike = uieditfield(app.Submit1AddLegsPanel, 'numeric');
            app.Submit1Strike.Limits = [0 Inf];
            app.Submit1Strike.RoundFractionalValues = 'on';
            app.SideLabel = uilabel(app.Submit1AddLegsPanel);
            app.SideLabel.Text = 'Right:';
            app.Submit1PutCall = uidropdown(app.Submit1AddLegsPanel);
            app.Submit1PutCall.Items = {'Put', 'Call'};
            app.Submit1PutCall.Value = 'Put';
            app.Submit1Status = uilabel(app.SubmitComboStopLimitTab);
            app.Submit1Status.Text = 'Status: Ready';
            app.OrderReportTab = uitab(app.TabGroup);
            app.OrderReportTab.Title = 'Order Report';
            app.ReportTable = uitable(app.OrderReportTab);
            app.ReportTable.ColumnName = {'Action'; 'Fin Instrument'; 'Price'; 'Time'; 'Commssion'; 'Quantity'};
            app.ReportTable.RowName = {};
            app.UpdateTable = uibutton(app.OrderReportTab, 'push');
            app.UpdateTable.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTable.Text = 'Update Table';
            app.LastUpdate = uilabel(app.OrderReportTab);
            app.LastUpdate.Text = 'Last Update: None';
            app.CancelOrderTab = uitab(app.TabGroup);
            app.CancelOrderTab.Title = 'Cancel Order';
            app.CancelOrderButton = uibutton(app.CancelOrderTab, 'push');
            app.CancelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @CancelOrderButtonPushed, true);
            app.CancelOrderButton.Text = 'Cancel Order';
            app.OrderIdEditFieldLabel = uilabel(app.CancelOrderTab);
            app.OrderIdEditFieldLabel.Text = 'Order Id:';
            app.OrderIdEditField = uieditfield(app.CancelOrderTab, 'numeric');
            app.CancelStatus = uilabel(app.CancelOrderTab);
            app.CancelStatus.Text = 'Status: Ready';
            app.SubmitSingleTab = uitab(app.TabGroup);
            app.SubmitSingleTab.Title = 'Submit Single';
            app.Submit1AddLegsPanel_3 = uipanel(app.SubmitSingleTab);
            app.Submit1AddLegsPanel_3.Title = 'Order single contract';
            app.SubmitSingle = uibutton(app.Submit1AddLegsPanel_3, 'push');
            app.SubmitSingle.ButtonPushedFcn = createCallbackFcn(app, @SubmitSingleButtonPushed, true);
            app.SubmitSingle.Text = 'Submit Order';
            app.StrikePriceLabel_3 = uilabel(app.Submit1AddLegsPanel_3);
            app.StrikePriceLabel_3.Text = 'Strike Price:';
            app.SideLabel_3 = uilabel(app.Submit1AddLegsPanel_3);
            app.SideLabel_3.Text = 'Right:';
            app.SideLabel_4 = uilabel(app.Submit1AddLegsPanel_3);
            app.SideLabel_4.Text = 'Action:';
            app.NumberOfLotsEditField_4Label_3 = uilabel(app.Submit1AddLegsPanel_3);
            app.NumberOfLotsEditField_4Label_3.Text = 'Number Of Lots:';
            app.OrderTypeLabel = uilabel(app.Submit1AddLegsPanel_3);
            app.OrderTypeLabel.Text = 'Order Type:';
            app.SingleStopPriceField = uieditfield(app.Submit1AddLegsPanel_3, 'text');
            app.SingleStopPriceField.Visible = 'off';
            app.SingleStopPriceLabel = uilabel(app.Submit1AddLegsPanel_3);
            app.SingleStopPriceLabel.Visible = 'off';
            app.SingleStopPriceLabel.Text = 'Stop Price:';
            app.SingleLimitPriceField = uieditfield(app.Submit1AddLegsPanel_3, 'text');
            app.SingleLimitPriceField.Visible = 'off';
            app.SingleLimitPriceLabel = uilabel(app.Submit1AddLegsPanel_3);
            app.SingleLimitPriceLabel.Visible = 'off';
            app.SingleLimitPriceLabel.Text = 'Limit Price:';
            app.SingleOrderType = uidropdown(app.Submit1AddLegsPanel_3);
            app.SingleOrderType.Items = {'Market', 'Limit', 'Stop', 'Stop Limit'};
            app.SingleOrderType.ValueChangedFcn = createCallbackFcn(app, @SingleOrderTypeValueChanged, true);
            app.SingleOrderType.Value = 'Market';
            app.SingleNumOfLots = uieditfield(app.Submit1AddLegsPanel_3, 'numeric');
            app.SingleNumOfLots.Limits = [1 Inf];
            app.SingleNumOfLots.Value = 1;
            app.SingleRight = uidropdown(app.Submit1AddLegsPanel_3);
            app.SingleRight.Items = {'Put', 'Call'};
            app.SingleRight.Value = 'Put';
            app.SingleAction = uidropdown(app.Submit1AddLegsPanel_3);
            app.SingleAction.Items = {'Buy', 'Sell'};
            app.SingleAction.Value = 'Buy';
            app.SingleStrike = uieditfield(app.Submit1AddLegsPanel_3, 'numeric');
            app.SingleStrike.Limits = [0 Inf];
            app.SingleStrike.RoundFractionalValues = 'on';
            app.SingleStatusLabel = uilabel(app.SubmitSingleTab);
            app.SingleStatusLabel.Text = 'Status: Ready';
            app.SubmitComboTab = uitab(app.TabGroup);
            app.SubmitComboTab.Title = 'Submit Combo';
            app.Submit2OrderSummaryPanel = uipanel(app.SubmitComboTab);
            app.Submit2OrderSummaryPanel.Title = 'Order Summary';
            app.Submit2ReciptSubmit = uibutton(app.Submit2OrderSummaryPanel, 'push');
            app.Submit2ReciptSubmit.ButtonPushedFcn = createCallbackFcn(app, @Submit2ReciptSubmitButtonPushed, true);
            app.Submit2ReciptSubmit.Text = 'Submit Order';
            app.Submit2ProfitTakerLabel = uilabel(app.Submit2OrderSummaryPanel);
            app.Submit2ProfitTakerLabel.Text = 'Profit Taker Limit Price:';
            app.Submit2ReciptProfitTakerEnable = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptProfitTakerEnable.Editable = 'off';
            app.NumberOfLotsEditField_2Label_2 = uilabel(app.Submit2OrderSummaryPanel);
            app.NumberOfLotsEditField_2Label_2.Text = 'Number Of Lots:';
            app.StopLossTypeEditFieldLabel_2 = uilabel(app.Submit2OrderSummaryPanel);
            app.StopLossTypeEditFieldLabel_2.Text = 'Stop Loss Type:';
            app.StopLossStopTriggerLabel_2 = uilabel(app.Submit2OrderSummaryPanel);
            app.StopLossStopTriggerLabel_2.Text = 'Stop Loss Stop Trigger:';
            app.Submit2ReciptSTPLOSLimit = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptStopLossStopLimitLabel = uilabel(app.Submit2OrderSummaryPanel);
            app.Submit2ReciptStopLossStopLimitLabel.Text = 'Stop Loss Stop Limit:';
            app.Submit2ReciptSTPLOSStop = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptSTPLOSType = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptSTPLOSType.Editable = 'off';
            app.Submit2ReciptNumOfLot = uieditfield(app.Submit2OrderSummaryPanel, 'numeric');
            app.Submit2ReciptNumOfLot.Limits = [1 Inf];
            app.Submit2ReciptNumOfLot.Editable = 'off';
            app.Submit2ReciptNumOfLot.Value = 1;
            app.Submit2ReciptStop = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptStop.Editable = 'off';
            app.Submit2ReciptStopPriceLabel = uilabel(app.Submit2OrderSummaryPanel);
            app.Submit2ReciptStopPriceLabel.Text = 'Parent Order Stop Price:';
            app.Submit2ReciptLimit = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptLimit.Editable = 'off';
            app.Submit2ReciptLimitPriceLabel = uilabel(app.Submit2OrderSummaryPanel);
            app.Submit2ReciptLimitPriceLabel.Text = 'Parent Order Limit Price:';
            app.Submit2ReciptParentOrderType = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptParentOrderType.Editable = 'off';
            app.ParentOrderTypeEditFieldLabel = uilabel(app.Submit2OrderSummaryPanel);
            app.ParentOrderTypeEditFieldLabel.Text = 'Parent Order Type:';
            app.ProfitTakerEditFieldLabel = uilabel(app.Submit2OrderSummaryPanel);
            app.ProfitTakerEditFieldLabel.Text = 'Profit Taker:';
            app.Submit2ReciptClearTableBtn = uibutton(app.Submit2OrderSummaryPanel, 'push');
            app.Submit2ReciptClearTableBtn.ButtonPushedFcn = createCallbackFcn(app, @Submit2ReciptClearTableBtnButtonPushed, true);
            app.Submit2ReciptClearTableBtn.Text = 'Clear Table';
            app.Submit2Table = uitable(app.Submit2OrderSummaryPanel);
            app.Submit2Table.ColumnName = {'Strike Price'; 'Action'; 'Side'};
            app.Submit2Table.RowName = {};
            app.ParentOrderActionEditFieldLabel = uilabel(app.Submit2OrderSummaryPanel);
            app.ParentOrderActionEditFieldLabel.Text = 'Parent Order Action:';
            app.ParentOrderActionEditField = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ReciptProfitTaker = uieditfield(app.Submit2OrderSummaryPanel, 'text');
            app.Submit2ProfitTakerPanel = uipanel(app.SubmitComboTab);
            app.Submit2ProfitTakerPanel.Title = 'Add Profit Taker';
            app.Submit2ProfitTakerBtn = uibutton(app.Submit2ProfitTakerPanel, 'push');
            app.Submit2ProfitTakerBtn.ButtonPushedFcn = createCallbackFcn(app, @Submit2ProfitTakerBtnButtonPushed, true);
            app.Submit2ProfitTakerBtn.Text = 'Add Edits';
            app.Submit2ProfitTakerField = uieditfield(app.Submit2ProfitTakerPanel, 'text');
            app.ProfitTakerLimitPriceLabel = uilabel(app.Submit2ProfitTakerPanel);
            app.ProfitTakerLimitPriceLabel.Text = 'Profit Taker Limit Price:';
            app.Submit2ProfitTakerCb = uicheckbox(app.Submit2ProfitTakerPanel);
            app.Submit2ProfitTakerCb.ValueChangedFcn = createCallbackFcn(app, @Submit2ProfitTakerCbValueChanged, true);
            app.Submit2ProfitTakerCb.Text = 'Profit Taker (Limit Order)';
            app.Submit2ProfitTakerCb.Value = true;
            app.Submit2AddStopLossPanel = uipanel(app.SubmitComboTab);
            app.Submit2AddStopLossPanel.Title = 'Add Stop Loss';
            app.StopLossOrderTypeDropDown_2Label_2 = uilabel(app.Submit2AddStopLossPanel);
            app.StopLossOrderTypeDropDown_2Label_2.Text = 'Stop Loss Order Type:';
            app.Submit2STPLOSOrderType = uidropdown(app.Submit2AddStopLossPanel);
            app.Submit2STPLOSOrderType.Items = {'Stop Market', 'Stop Limit'};
            app.Submit2STPLOSOrderType.ValueChangedFcn = createCallbackFcn(app, @Submit2STPLOSOrderTypeValueChanged, true);
            app.Submit2STPLOSOrderType.Value = 'Stop Market';
            app.Submit2AddEditsSTPLOS = uibutton(app.Submit2AddStopLossPanel, 'push');
            app.Submit2AddEditsSTPLOS.ButtonPushedFcn = createCallbackFcn(app, @Submit2AddEditsSTPLOSButtonPushed, true);
            app.Submit2AddEditsSTPLOS.Text = 'Add Edits';
            app.Submit2STPLOSLimit = uieditfield(app.Submit2AddStopLossPanel, 'text');
            app.Submit2STPLOSLimit.Visible = 'off';
            app.StopLossStopTriggerEditField_2Label_2 = uilabel(app.Submit2AddStopLossPanel);
            app.StopLossStopTriggerEditField_2Label_2.Text = 'Stop Loss Stop Trigger:';
            app.Submit2STPLOSStop = uieditfield(app.Submit2AddStopLossPanel, 'text');
            app.Submit2StopLossStopLimitLabel = uilabel(app.Submit2AddStopLossPanel);
            app.Submit2StopLossStopLimitLabel.Visible = 'off';
            app.Submit2StopLossStopLimitLabel.Text = 'Stop Loss Stop Limit:';
            app.Submit2EditParentOrderPanel = uipanel(app.SubmitComboTab);
            app.Submit2EditParentOrderPanel.Title = 'Edit Parent Order';
            app.Submit2ParentLimit = uieditfield(app.Submit2EditParentOrderPanel, 'text');
            app.Submit2ParentLimit.Visible = 'off';
            app.Submit2ParentStop = uieditfield(app.Submit2EditParentOrderPanel, 'text');
            app.Submit2ParentStop.Visible = 'off';
            app.Submit2AddEditsParent = uibutton(app.Submit2EditParentOrderPanel, 'push');
            app.Submit2AddEditsParent.ButtonPushedFcn = createCallbackFcn(app, @Submit2AddEditsParentButtonPushed, true);
            app.Submit2AddEditsParent.Text = 'Add Edits';
            app.Submit2NumOfLots = uieditfield(app.Submit2EditParentOrderPanel, 'numeric');
            app.Submit2NumOfLots.Limits = [1 Inf];
            app.Submit2NumOfLots.Value = 1;
            app.ActionDropDownLabel = uilabel(app.Submit2EditParentOrderPanel);
            app.ActionDropDownLabel.Text = 'Action:';
            app.ActionDropDown = uidropdown(app.Submit2EditParentOrderPanel);
            app.ActionDropDown.Items = {'Buy', 'Sell'};
            app.ActionDropDown.Value = 'Buy';
            app.NumberOfLotsEditField_4Label_2 = uilabel(app.Submit2EditParentOrderPanel);
            app.NumberOfLotsEditField_4Label_2.Text = 'Number Of Lots:';
            app.ParentOrderTypeDropDownLabel = uilabel(app.Submit2EditParentOrderPanel);
            app.ParentOrderTypeDropDownLabel.Text = 'Parent Order Type:';
            app.Submit2ParentOrderType = uidropdown(app.Submit2EditParentOrderPanel);
            app.Submit2ParentOrderType.Items = {'Market', 'Limit', 'Stop', 'Stop Limit'};
            app.Submit2ParentOrderType.ValueChangedFcn = createCallbackFcn(app, @Submit2ParentOrderTypeValueChanged, true);
            app.Submit2ParentOrderType.Value = 'Market';
            app.Submit2ParentOrderLimitPriceLabel = uilabel(app.Submit2EditParentOrderPanel);
            app.Submit2ParentOrderLimitPriceLabel.Visible = 'off';
            app.Submit2ParentOrderLimitPriceLabel.Text = 'Parent Order Limit Price:';
            app.Submit2ParentOrderStopPriceLabel = uilabel(app.Submit2EditParentOrderPanel);
            app.Submit2ParentOrderStopPriceLabel.Visible = 'off';
            app.Submit2ParentOrderStopPriceLabel.Text = 'Parent Order Stop Price:';
            app.Submit2AddLegsPanel = uipanel(app.SubmitComboTab);
            app.Submit2AddLegsPanel.Title = 'Add Legs';
            app.Submit2AddLegBtn = uibutton(app.Submit2AddLegsPanel, 'push');
            app.Submit2AddLegBtn.ButtonPushedFcn = createCallbackFcn(app, @Submit2AddLegBtnButtonPushed, true);
            app.Submit2AddLegBtn.Text = 'Add Leg';
            app.StrikePriceLabel_2 = uilabel(app.Submit2AddLegsPanel);
            app.StrikePriceLabel_2.Text = 'Strike Price:';
            app.Submit2AddLegStrikeField = uieditfield(app.Submit2AddLegsPanel, 'numeric');
            app.Submit2AddLegStrikeField.Limits = [0 Inf];
            app.Submit2AddLegStrikeField.RoundFractionalValues = 'on';
            app.SideLabel_2 = uilabel(app.Submit2AddLegsPanel);
            app.SideLabel_2.Text = 'Right:';
            app.Submit2AddLegRightDd = uidropdown(app.Submit2AddLegsPanel);
            app.Submit2AddLegRightDd.Items = {'Put', 'Call'};
            app.Submit2AddLegRightDd.Value = 'Put';
            app.SideLabel_5 = uilabel(app.Submit2AddLegsPanel);
            app.SideLabel_5.Text = 'Action:';
            app.Submit2AddLegActionDd = uidropdown(app.Submit2AddLegsPanel);
            app.Submit2AddLegActionDd.Items = {'Buy', 'Sell'};
            app.Submit2AddLegActionDd.Value = 'Buy';
            app.Submit2Status = uilabel(app.SubmitComboTab);
            app.Submit2Status.Text = 'Status: Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end